classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResetButton            matlab.ui.control.Button
        TourButton             matlab.ui.control.Button
        oderLabel              matlab.ui.control.Label
        WhleeineSzeneausLabel  matlab.ui.control.Label
        Image6                 matlab.ui.control.Image
        Image5                 matlab.ui.control.Image
        Image4                 matlab.ui.control.Image
        Image3                 matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        Image                  matlab.ui.control.Image
        UploadButton           matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

methods (Access = private)
    
    function calculations(app)
        
        %Benutzung: hier muss man aktuell den oberen linken und unteren rechten Punkt und dann den Fluchtpunkt wählen!
        %im = getframe(app.UIAxes);
        im = getimage(app.UIAxes);
        f1 = figure('Name', 'Auswahl Hintergrund und Fluchtpunkt', 'NumberTitle','off');
        figure(f1)
        [v,irx,iry,orx,ory] = Background_gui(im);
        close(f1);

        %% Background
        [big_image,v,plane2d] = poly_decomp(im,v,irx,iry,orx,ory);
        [plane3d] = Points3D(big_image, v, plane2d);

        %% Foreground
        %Benutzung: hier muss man aktuell ein Rechteck für jedes Objekt aufziehen!
        %--> bisher nur am Boden!
        [background, object_img, obj2d] = Foreground(big_image,plane2d);
        [obj3d] = Points3D_Obj(big_image, plane2d, v, obj2d);

        %% Rendering
        Rendering(background, object_img, plane2d, plane3d, obj2d, obj3d);      

    end

end
%     methods (Access = private)
% 
%     end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename,pathname]=uigetfile({'*.*'});
            if isequal(filename,0) ||isequal(pathname,0)
                errordlg("Kein Bild ausgewählt","Error");
            else
                file = strcat(pathname,filename);
            end
            im = imread(file);

            res = size(im,1)*size(im,2);

            if res > 8*10^6
                r = 8*10^6;
                im = imresize(im,r);
            end

            imshow(im,'Parent',app.UIAxes);
            
        end

        % Button pushed function: TourButton
        function TourButtonPushed(app, event)
            isEmpytAxes = isempty(get(app.UIAxes, 'Children'));
            if isEmpytAxes == true
                return
            else
                calculations(app);
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Image clicked function: Image
        function simpleAClicked(app, event)
            file = "Gui_photos/Simple_a.png";
            im = imread(file);

            res = size(im,1)*size(im,2);

            if res > 8*10^6
                r = 8*10^6;
                im = imresize(im,r);
            end
            
            imshow(im,'Parent',app.UIAxes);
        end

        % Image clicked function: Image2
        function simpleBClicked(app, event)
            file = "Gui_photos/Simple_b.jpg";
            im = imread(file);

            res = size(im,1)*size(im,2);

            if res > 8*10^6
                r = 8*10^6;
                im = imresize(im,r);
            end

            imshow(im,'Parent',app.UIAxes);
        end

        % Image clicked function: Image4
        function mittelBClicked(app, event)
            file = "Gui_photos/Mittel_b.png";
            im = imread(file);

            res = size(im,1)*size(im,2);

            if res > 8*10^6
                r = 8*10^6;
                im = imresize(im,r);
            end

            imshow(im, 'Parent', app.UIAxes);
        end

        % Image clicked function: Image3
        function mittelAClicked(app, event)
            file = "Gui_photos/Mittel_a.png";
            im = imread(file);

            res = size(im,1)*size(im,2);

            if res > 8*10^6
                r = 8*10^6;
                im = imresize(im,r);
            end

            imshow(im,'Parent',app.UIAxes);
        end

        % Image clicked function: Image6
        function schwierigBClicked(app, event)
            file = "Gui_photos/Schwierig_b.png";
            im = imread(file);

            res = size(im,1)*size(im,2);

            if res > 8*10^6
                r = 8*10^6;
                im = imresize(im,r);
            end

            imshow(im,'Parent',app.UIAxes);
        end

        % Image clicked function: Image5
        function schwierigAClicked(app, event)
            file = "Gui_photos/Schwierig_a.png";
            im = imread(file);

            res = size(im,1)*size(im,2);

            if res > 8*10^6
                r = 8*10^6;
                im = imresize(im,r);
            end

            imshow(im,'Parent',app.UIAxes);
        end

        % Button pushed function: ResetButton
        function reset(app, event)
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ausgewähltes Bild')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @simpleAClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Gui_photos', 'Simple_a.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @simpleBClicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Gui_photos', 'Simple_b.jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @mittelAClicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Gui_photos', 'Mittel_a.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @mittelBClicked, true);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Gui_photos', 'Mittel_b.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @schwierigAClicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Gui_photos', 'Schwierig_a.png');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @schwierigBClicked, true);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Gui_photos', 'Schwierig_b.png');
            app.WhleeineSzeneausLabel = uilabel(app.UIFigure);
            app.WhleeineSzeneausLabel.Text = 'Wähle eine Szene aus';
            app.oderLabel = uilabel(app.UIFigure);
            app.oderLabel.Text = 'oder';
            app.TourButton = uibutton(app.UIFigure, 'push');
            app.TourButton.ButtonPushedFcn = createCallbackFcn(app, @TourButtonPushed, true);
            app.TourButton.Text = 'Tour';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end